classdef game1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PlayPanel       matlab.ui.container.Panel
        WordsLeftLabel  matlab.ui.control.Label
        SubmitButton    matlab.ui.control.Button
        EditField5      matlab.ui.control.EditField
        EditField4      matlab.ui.control.EditField
        EditField3      matlab.ui.control.EditField
        EditField2      matlab.ui.control.EditField
        EditField       matlab.ui.control.EditField
        Button5         matlab.ui.control.Button
        Button4         matlab.ui.control.Button
        Button3         matlab.ui.control.Button
        Button2         matlab.ui.control.Button
        Button          matlab.ui.control.Button
        GameModePanel   matlab.ui.container.Panel
        ReadyButton     matlab.ui.control.Button
        GameModeButton  matlab.ui.control.Button
        GameModeLabel   matlab.ui.control.Label
        WelcomePanel    matlab.ui.container.Panel
        StartButton     matlab.ui.control.Button
        WelcomeLabel    matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DraggingTile  matlab.ui.control.Button
    DragLabel matlab.ui.control.Label
    OriginalPosition
    OffsetX double = 0
    OffsetY double = 0
    end
    
    methods (Access = private)

         function checkWord(app)
            % Collect the text from each word slot
            wordSlots = [app.EditField, app.EditField2, app.EditField3, app.EditField4, app.EditField5];
            formedWord = '';

            % Loop through each slot and concatenate the text
            for i = 1:length(wordSlots)
                formedWord = [formedWord, wordSlots(i).Value];  % Concatenate the letter in the slot
            end

            % Check if the formed word is valid (you could add your own dictionary here)
            isValid = app.isValidWord(formedWord);

            % Display a message based on whether the word is valid or not
            if isValid
                uialert(app.UIFigure, 'Word is valid!', 'Word Check');
            else
                uialert(app.UIFigure, 'Word is invalid. Try again!', 'Word Check');
            end
        end

        % Function to check if the formed word is valid
        function isValid = isValidWord(app, word)
            % Here you can check the word against a dictionary or some other logic
            % For demonstration purposes, let's just check if the word is a non-empty string
            validWords = {'apple', 'banana', 'cherry'};  % A simple list of valid words
            isValid = any(strcmpi(word, validWords));  % Check if the word is in the list
        end

        % Other existing functions (dragTile, startTileDrag, dropTile, etc.)
        function startTileDrag(app, tile)
            app.DraggingTile = tile;
        end

        function dragLabel(app, event)
    if ~isempty(app.DragLabel)
        % Get current mouse position
        cp = get(app.PlayPanel, 'CurrentPoint');
        
        % Update label position with fixed size
        width = app.DragLabel.Position(3);
        height = app.DragLabel.Position(4);
        app.DragLabel.Position = [cp(1)-width/2, cp(2)-height/2, width, height];
    end
        end

        function dropLabel(app, event)
    % Clean up event handlers
    set(app.UIFigure, 'WindowButtonMotionFcn', '');
    set(app.UIFigure, 'WindowButtonUpFcn', '');
    
    if ~isempty(app.DragLabel) && ~isempty(app.DraggingTile)
        % Check if the label is over any word slots
        WordSlots = [app.EditField, app.EditField2, app.EditField3, app.EditField4, app.EditField5];
        placed = false;
        
        for i = 1:length(WordSlots)
            if app.isOverlapping(app.DragLabel, WordSlots(i))
                % Set the word slot value to the tile's text
                WordSlots(i).Value = app.DraggingTile.Text;
                placed = true;
                break;
            end
        end
        
        % Restore original tile
        app.DraggingTile.Visible = 'on';
        
        % Delete temporary label
        delete(app.DragLabel);
        app.DragLabel = [];
        
        % Clear dragging tile
        app.DraggingTile = [];
    end
end

        function dragTile(app, event)
           if ~isempty(app.DraggingTile)
        % Get figure position
        figPos = app.UIFigure.Position;
        
        % Get mouse position in screen coordinates 
        mousePos = get(0, 'PointerLocation');
        
        % Get UIFigure position in screen coordinates
        figScreenPos = get(app.UIFigure, 'Position');
        
        % Calculate position within UIFigure's coordinate system
        % This accounts for the figure's position on screen
        relX = mousePos(1) - figScreenPos(1);
        relY = mousePos(2) - figScreenPos(2);
        
        % App Designer uses top-left as (0,0), so flip Y coordinate
        relY = figScreenPos(4) - relY;
        
        % Get panel position (if tiles are in a panel)
        % If your tiles are in app.PlayPanel, we need to adjust for that
        panelPos = app.PlayPanel.Position;
        
        % Adjust for panel's position
        relX = relX - panelPos(1);
        relY = relY - panelPos(2);
        
        % Get tile dimensions
        tileWidth = 35;  % Hardcode this to match your tile width
        tileHeight = 35; % Hardcode this to match your tile height
        
        % Position tile centered at cursor
        newX = relX - (tileWidth/2);
        newY = relY - (tileHeight/2);
        
        % Update position
        app.DraggingTile.Position = [newX, newY, tileWidth, tileHeight];
    end
        end

        function dropTile(app, event)
            set(app.UIFigure, 'WindowButtonMotionFcn', '');
            set(app.UIFigure, 'WindowButtonUpFcn', '');

            if ~isempty(app.DraggingTile)
                   WordSlots = [app.EditField, app.EditField2, app.EditField3, app.EditField4, app.EditField5];
                   placed = false;

            for i = 1:length(WordSlots)
                if app.isOverlapping(app.DraggingTile, WordSlots(i))
                    app.DraggingTile.Position = WordSlots(i).Position;

                    WordSlots(i).Value = app.DraggingTile.Text;

                    placed = true;
                    break;
                end
            end

            app.DraggingTile = [];
            end
        end

        function overlap = isOverlapping(app, tile, WordSlot)
            tilePos = tile.Position;
            slotPos = WordSlot.Position;
            overlap = (tilePos(1) < slotPos(1) + slotPos(3)) && ...
                      (tilePos(1) + tilePos(3) > slotPos(1)) && ...
                      (tilePos(2) < slotPos(2) + slotPos(4)) && ...
                      (tilePos(2) + tilePos(4) > slotPos(2));
        end
    function showGameModeScreen(app)
        % Hide the WelcomePanel and show the GameModePanel
        app.WelcomePanel.Visible = 'off';  % Hide WelcomePanel
        app.GameModePanel.Visible = 'on';  % Show GameModePanel
    end

    function showFiveNormalScreen(app)
        % Hide the GameModePanel and show the PlayPanel
        app.GameModePanel.Visible = 'off';  % Hide GameModePanel
        app.PlayPanel.Visible = 'on';       % Show PlayPanel (your game screen)
    end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.showGameModeScreen();
        end

        % Button pushed function: GameModeButton
        function GameModeButtonPushed(app, event)
            app.ReadyButton.Visible = 'on';
        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed(app, event)
            app.showFiveNormalScreen();
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            app.checkWord();
        end

        % Button pushed function: Button, Button2, Button3, Button4, 
        % ...and 1 other component
        function LetterTilesClicked(app, event)
            app.DraggingTile = event.Source;
    
    % Remember original position
    app.OriginalPosition = app.DraggingTile.Position;
    
    % Create a temporary label to show during dragging
    app.DragLabel = uilabel(app.PlayPanel);
    app.DragLabel.Text = app.DraggingTile.Text;
    app.DragLabel.Position = app.DraggingTile.Position;
    app.DragLabel.BackgroundColor = app.DraggingTile.BackgroundColor;
    app.DragLabel.HorizontalAlignment = 'center';
    app.DragLabel.VerticalAlignment = 'center';
    
    % Hide original button
    app.DraggingTile.Visible = 'off';
    
    % Set up event handlers
    set(app.UIFigure, 'WindowButtonMotionFcn', @(src,event)app.dragLabel(event));
    set(app.UIFigure, 'WindowButtonUpFcn', @(src,event)app.dropLabel(event));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomePanel = uipanel(app.UIFigure);
            app.WelcomePanel.Title = 'Welcome Panel';
            app.UIAxes = uiaxes(app.WelcomePanel);
            title(app.UIAxes, 'WelcomeAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WelcomeLabel = uilabel(app.WelcomePanel);
            app.WelcomeLabel.Text = 'WelcomeLabel';
            app.StartButton = uibutton(app.WelcomePanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'StartButton';
            app.GameModePanel = uipanel(app.UIFigure);
            app.GameModePanel.Title = 'GameModePanel';
            app.GameModeLabel = uilabel(app.GameModePanel);
            app.GameModeLabel.Text = 'GameModeLabel';
            app.GameModeButton = uibutton(app.GameModePanel, 'push');
            app.GameModeButton.ButtonPushedFcn = createCallbackFcn(app, @GameModeButtonPushed, true);
            app.GameModeButton.Text = 'GameModeButton';
            app.ReadyButton = uibutton(app.GameModePanel, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed, true);
            app.ReadyButton.Text = 'ReadyButton';
            app.PlayPanel = uipanel(app.UIFigure);
            app.PlayPanel.Title = 'PlayPanel';
            app.Button = uibutton(app.PlayPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button.Tag = 'LetterTiles';
            app.Button2 = uibutton(app.PlayPanel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button2.Tag = 'LetterTiles';
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.PlayPanel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button3.Tag = 'LetterTiles';
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.PlayPanel, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button4.Tag = 'LetterTiles';
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.PlayPanel, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button5.Tag = 'LetterTiles';
            app.Button5.Text = 'Button5';
            app.EditField = uieditfield(app.PlayPanel, 'text');
            app.EditField.Tag = 'WordSlots';
            app.EditField2 = uieditfield(app.PlayPanel, 'text');
            app.EditField2.Tag = 'WordSlots';
            app.EditField3 = uieditfield(app.PlayPanel, 'text');
            app.EditField3.Tag = 'WordSlots';
            app.EditField4 = uieditfield(app.PlayPanel, 'text');
            app.EditField4.Tag = 'WordSlots';
            app.EditField5 = uieditfield(app.PlayPanel, 'text');
            app.EditField5.Tag = 'WordSlots';
            app.SubmitButton = uibutton(app.PlayPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'SubmitButton';
            app.WordsLeftLabel = uilabel(app.PlayPanel);
            app.WordsLeftLabel.Text = 'WordsLeftLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end